classdef OrientationAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        IntensityPlotButton       matlab.ui.control.Button
        ExportDataButton          matlab.ui.control.Button
        ReadBrushButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        ClearallButton            matlab.ui.control.Button
        DeleteRowButton           matlab.ui.control.Button
        OirentationButton         matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        SpectraTypeDropDownLabel  matlab.ui.control.Label
        DropDown_SpectraType      matlab.ui.control.DropDown
    end


    properties (Access = public)
        Parent % handles of parent
        CurrentFig
        Data_Brush
        Data_Spec % StructureData Class
        Response_V  
        SelectedRow
        IntGrid
        N_Brished % Description
    end

    methods (Access = private)
    
        function Update_OrientTable(app)
            %% Reorginze Data_Brush
            Data_Table = reshape([app.Data_Brush(:).TableRow],3,[])';
            Row_Color  = reshape([app.Data_Brush(:).Color],3,[])';
            
            %% Initial vlues of the Orientation inputs
            ColumnName     = {       '', 'Intensity', 'Ratio'};
            ColumnFormat   = {'logical',      'char',  'bank'};
            ColumnEditable = [     true,       false,    true];
            ColumnWidth    = {       20,         80,      60};
            
            app.UITable.ColumnName      = ColumnName;
            app.UITable.ColumnFormat    = ColumnFormat;
            app.UITable.ColumnEditable  = ColumnEditable;
            app.UITable.ColumnWidth     = ColumnWidth;
            app.UITable.RowName         = 'numbered';
            app.UITable.BackgroundColor = Row_Color;
            app.UITable.Data            = Data_Table;
        end
        
        function Read_SpecData(app)
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    app.Data_Spec = app.Parent.Data_SFG;
                case '2DSFG'
                    app.Data_Spec = app.Parent.Data_2DSFG;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
            else
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % initialize the UIAxes and Table
            ClearallButtonPushed(app,'')
        end

        % Button pushed function: IntensityPlotButton
        function IntensityPlotButtonPushed(app, event)
            Read_SpecData(app)
            Spec = app.Data_Spec;
            
            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.PlotNorm  = 1;
            
            app.CurrentFig = figure;
            hAx = axes('Parent',app.CurrentFig);
            
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    COSMOSS_Input.Lineshape = 'Lorentzian';
                    Spec.Response1D = Spec.Res_Int;
                    Plot1D(hAx,Spec,COSMOSS_Input);
                    hold(hAx,'on')
                        X = Spec.freq_OneD;
                        Y = Spec.Res_Int;
                        Y = Y./max(abs(Y(:)));
                        scatter(hAx,X,Y,10,'filled')
                    hold(hAx,'off')   
                    hAx.YLim = [0,1.1];
                case '2DSFG'
                    COSMOSS_Input.Lineshape = 'No Conv';
                    CVL = Conv2D(Spec.Response.IntGrid,COSMOSS_Input);
                    CVL.FilesName = 'Response intensity plot'; % pass filesname for figure title
                    Plot2D(hAx,CVL,COSMOSS_Input,'2DSFG Int');
                    
                    hAx.Color = [0,0,1];
                    colormap(hAx,'hot')
                    caxis(hAx,[0,1])
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data_Orient.Data_Spec  = app.Data_Spec;
            Data_Orient.Data_Brush = app.Data_Brush;
            Data_Orient.Response_V = app.Response_V;
            Data_Orient.Data_Table = app.UITable.Data;
            
            assignin('base','Data_Orient',Data_Orient)
            disp('Updated Data_Orient exported!')
        end

        % Button pushed function: ReadBrushButton
        function ReadBrushButtonPushed(app, event)
            N = app.N_Brished + 1; % update counter
            app.N_Brished = N;
            
            % Read brush data from the Intensity Plot
            hF = app.CurrentFig;
            hS = findobj(hF,'Type','Scatter');
            hAx = findobj(hF,'Type','Axes');
            
            
            BrushedIdx = logical(hS.BrushData);
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    app.UIAxes.XLim = hAx.XLim;
                    Sort_Ex_F1 = app.Data_Spec.H.Sort_Ex_F1;
                    if iscolumn(Sort_Ex_F1)
                        Sort_Ex_F1 = Sort_Ex_F1';
                    end
                    FreqSub     = hS.XData(BrushedIdx)';
                    [~,FreqInd] = min(abs( bsxfun(@minus,FreqSub,Sort_Ex_F1) ),[],2);
                    Beta = app.Data_Spec.MolFrame(:,FreqInd);
                    Beta = sum(Beta,2);
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = zeros(size(Scatter_X));
                    MarkerSize = 100;
                case '2DSFG'
                    app.UIAxes.XLim = hAx.XLim;
                    app.UIAxes.YLim = hAx.YLim;
                    
                    FreqSub    = [hS.YData(BrushedIdx)',hS.XData(BrushedIdx)']; % [Pump,Probe]
                    SparseMax  = app.Data_Spec.Response.SparseMax;
                    FreqInd    = sub2ind([SparseMax,SparseMax],FreqSub(:,1),FreqSub(:,2));
                    
                    % Update the Brushed response
                    Beta =       app.Data_Spec.Response.Beta.R1(FreqInd,:);
                    Beta = [Beta;app.Data_Spec.Response.Beta.R2(FreqInd,:);];
                    Beta = [Beta;app.Data_Spec.Response.Beta.R3(FreqInd,:);];
                    Beta = [Beta;app.Data_Spec.Response.Beta.NR1(FreqInd,:);];
                    Beta = [Beta;app.Data_Spec.Response.Beta.NR2(FreqInd,:);];
                    Beta = [Beta;app.Data_Spec.Response.Beta.NR3(FreqInd,:);];
                    Beta = sum(Beta,1)';
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = hS.YData(BrushedIdx)';
                    MarkerSize = 150;
            end
            app.Response_V(:,N)= Beta;
            
            % update new info of table row
            Int  = full(sqrt(sum(Beta.^2)));
            TableRow = {true,sprintf('%3.2e',Int),1};
            BrushedColor = app.UIAxes.ColorOrder(N,:);
            
            % Update the UIAxes on GUI
            hold(app.UIAxes,'on')
                hScatter = scatter(app.UIAxes,...
                                   Scatter_X,Scatter_Y,...
                                   MarkerSize,...
                                   'filled',...
                                   'MarkerFaceColor',BrushedColor);
            hold(app.UIAxes,'off')
            
            % export Data_Brush to app and update the UITable
            app.Data_Brush(N).BrushedIdx = BrushedIdx;
            app.Data_Brush(N).FreqSub    = FreqSub;
            app.Data_Brush(N).FreqInd    = FreqInd;
            app.Data_Brush(N).Color      = BrushedColor;
            app.Data_Brush(N).hScatter   = hScatter;
            app.Data_Brush(N).TableRow   = TableRow;
            Update_OrientTable(app)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            % Reset other variable
            app.N_Brished  = 0;
            app.Response_V = [];
            
            % clean up Data_Brush
            app.Data_Brush = [];
            app.Data_Brush.BrushedIdx = [];
            app.Data_Brush.FreqSub    = [];
            app.Data_Brush.FreqInd    = [];
            app.Data_Brush.Color      = [1,1,1];
            app.Data_Brush.hScatter   = [];
            app.Data_Brush.TableRow   = {'','',''};
            Update_OrientTable(app)
            
            % make default figure
            cla(app.UIAxes,'reset')
            
            SpecType = app.DropDown_SpectraType.Value;
            Read_SpecData(app)

            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.PlotCursor = 0;
            COSMOSS_Input.PlotNorm   = 1;
            COSMOSS_Input.Lineshape  = 'Lorentzian';
            
            Read_SpecData(app)
            switch SpecType
                case 'SFG'
                    Plot1D(app.UIAxes,app.Data_Spec,COSMOSS_Input);
                case '2DSFG'
                    COSMOSS_Input.CMAP_Index = 'JetWhiteWide';
                    
                    CVL = Conv2D(app.Data_Spec.SpectraGrid,COSMOSS_Input);
                    CVL.FilesName = app.Parent.Structure.FilesName; % pass filesname for figure title
                    Plot2D(app.UIAxes,CVL,COSMOSS_Input,app.Data_Spec.Response.SpecType);
                    
                    F_Array = COSMOSS_Input.F_Min:COSMOSS_Input.F_Max;
                    app.UIAxes.XLim = [F_Array(1),F_Array(end)];
                    app.UIAxes.YLim = [F_Array(1),F_Array(end)];
            end
            % reset color order
            app.UIAxes.ColorOrderIndex = 1;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            Delete_Ind = app.SelectedRow;
            % update counter
            app.N_Brished = app.N_Brished - length(Delete_Ind);
            % delete scatter plot obj
            delete(app.Data_Brush(Delete_Ind).hScatter)
            % update Data_Brush
            app.Data_Brush(Delete_Ind) = [];
            Update_OrientTable(app)
        end

        % Button pushed function: OirentationButton
        function OirentationButtonPushed(app, event)
            COSMOSS_Input = app.Parent.Parse_GUI;
            
            SpecType = app.DropDown_SpectraType.Value;
            Orientation_Input.SpecType = SpecType;
            Orientation_Input.N_Grid   =30;
            EJLR_T = EJLR_Tensor(COSMOSS_Input,Orientation_Input);
            
            
            Selection_Ind = app.SelectedRow;
            app.IntGrid   = EJLR_T * app.Response_V(:,Selection_Ind);
            
            % visulize
            N_Grid = Orientation_Input.N_Grid;
            %V = reshape(app.IntGrid,2*N_Grid,N_Grid,2*N_Grid);
            %V = reshape(app.IntGrid,2*N_Grid,N_Grid);
            V = reshape(app.IntGrid,N_Grid,2*N_Grid);
            
            X = linspace(0,360,N_Grid*2)';
            Y = linspace(0,180,N_Grid)';
            Z = V(:,:,1);
            hF = figure;
            hAx = axes('Parent',hF);
            %contour(hAx,X,Y,Z',20)
            contourf(hAx,X,Y,Z,20)
            hAx.XLabel.String = '\phi';
            hAx.YLabel.String = '\theta';
            hAx.FontSize = 16;
            
            TitleStr = [SpecType,', selection #:',num2str(Selection_Ind)];
            hAx.Title.String = TitleStr;
            colormap(hAx,'Jet')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedRow = event.Indices(:,1);
        end

        % Value changed function: DropDown_SpectraType
        function DropDown_SpectraTypeValueChanged(app, event)
            ClearallButtonPushed(app, event)
            Read_SpecData(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Orientation Analysis';
            app.IntensityPlotButton = uibutton(app.UIFigure, 'push');
            app.IntensityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityPlotButtonPushed, true);
            app.IntensityPlotButton.Text = 'Intensity Plot';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ReadBrushButton = uibutton(app.UIFigure, 'push');
            app.ReadBrushButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBrushButtonPushed, true);
            app.ReadBrushButton.Text = 'Read Brush';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Regio of selection')
            xlabel(app.UIAxes, 'Probe (cm^{-1})')
            ylabel(app.UIAxes, 'Pump (cm^{-1})')
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.DeleteRowButton = uibutton(app.UIFigure, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.OirentationButton = uibutton(app.UIFigure, 'push');
            app.OirentationButton.ButtonPushedFcn = createCallbackFcn(app, @OirentationButtonPushed, true);
            app.OirentationButton.Text = 'Oirentation';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SpectraTypeDropDownLabel = uilabel(app.UIFigure);
            app.SpectraTypeDropDownLabel.Text = 'Spectra Type';
            app.DropDown_SpectraType = uidropdown(app.UIFigure);
            app.DropDown_SpectraType.Items = {'SFG', '2DSFG'};
            app.DropDown_SpectraType.ValueChangedFcn = createCallbackFcn(app, @DropDown_SpectraTypeValueChanged, true);
            app.DropDown_SpectraType.Value = 'SFG';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OrientationAnalysis(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end