classdef COSMOSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COSMOSS_UIFigure                matlab.ui.Figure
        Panel_Structure                 matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        Button_SelectModel              matlab.ui.control.Button
        Panel_Response                  matlab.ui.container.Panel
        LocalmodeFreqDropDownLabel      matlab.ui.control.Label
        LocalmodeFreqDropDown           matlab.ui.control.DropDown
        CouplingModelDropDownLabel      matlab.ui.control.Label
        CouplingModelDropDown           matlab.ui.control.DropDown
        BetaNNcm1Label                  matlab.ui.control.Label
        BetaNNcm1EditField              matlab.ui.control.NumericEditField
        FeynmannpathwayCutoffEditFieldLabel  matlab.ui.control.Label
        FeynmannpathwayCutoffEditField  matlab.ui.control.NumericEditField
        Panel_ExpSetup                  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DTab                            matlab.ui.container.Tab
        UITable_1D                      matlab.ui.control.Table
        DTab_2                          matlab.ui.container.Tab
        UITable_2D                      matlab.ui.control.Table
        Panel_FigOpt                    matlab.ui.container.Panel
        CheckBox_SaveFig                matlab.ui.control.CheckBox
        EditField_SavePath              matlab.ui.control.EditField
        CheckBox_Sticks                 matlab.ui.control.CheckBox
        CheckBox_Normalize              matlab.ui.control.CheckBox
        CheckBox_Cursor                 matlab.ui.control.CheckBox
        FreqMinLabel                    matlab.ui.control.Label
        EditField_FreqMin               matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        EditField_FreqMax               matlab.ui.control.NumericEditField
        LineShapeLabel                  matlab.ui.control.Label
        DropDown_LineShape              matlab.ui.control.DropDown
        WidthLabel                      matlab.ui.control.Label
        EditField_Width                 matlab.ui.control.NumericEditField
        ContourEditFieldLabel           matlab.ui.control.Label
        EditField_Contour               matlab.ui.control.NumericEditField
        ColorMapDropDownLabel           matlab.ui.control.Label
        ColorMapDropDown                matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        SpectralTypeDropDownLabel       matlab.ui.control.Label
        SpectralTypeDropDown            matlab.ui.control.DropDown
        PathwayDropDownLabel            matlab.ui.control.Label
        PathwayDropDown                 matlab.ui.control.DropDown
        Panel_AnalysisTools             matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        FTIRTab                         matlab.ui.container.Tab
        SFGTab                          matlab.ui.container.Tab
        DIRTab                          matlab.ui.container.Tab
        DSFGTab                         matlab.ui.container.Tab
        FTIRButton                      matlab.ui.control.Button
        SFGButton                       matlab.ui.control.Button
        DIRButton                       matlab.ui.control.Button
        DSFGButton                      matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        Panel_SampleSymmetry            matlab.ui.container.Panel
        RotationalAverageDropDownLabel  matlab.ui.control.Label
        RotationalAverageDropDown       matlab.ui.control.DropDown
        MirrorPlaneDropDownLabel        matlab.ui.control.Label
        MirrorPlaneDropDown             matlab.ui.control.DropDown
        Panel_EnsembleAverage           matlab.ui.container.Panel
        RandomSamplingCheckBox          matlab.ui.control.CheckBox
        SampleEditFieldLabel            matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.NumericEditField
        DynamicFigUpdateCheckBox        matlab.ui.control.CheckBox
        ContinueCheckBox                matlab.ui.control.CheckBox
        FluctuationCorrelationLabel     matlab.ui.control.Label
        FluctuationCorrelationEditField  matlab.ui.control.NumericEditField
        DiagonalDisorderFWHMcm1EditFieldLabel  matlab.ui.control.Label
        DiagonalDisorderFWHMcm1EditField  matlab.ui.control.NumericEditField
        OffdiagonalDisorderFWHMcm1EditFieldLabel  matlab.ui.control.Label
        OffdiagonalDisorderFWHMcm1EditField  matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        hModel % handle of modeling GUI
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Initialization
            GUI_Setup(app)
        end

        % Button pushed function: Button_SelectModel
        function Button_SelectModelPushed(app, event)
            StructModel = str2double(app.ListBox.Value);
            [fhModel,ModelList,~] = StructureModel(StructModel);
            
            app.hModel = feval(fhModel,'COSMOSS',app);
            disp(['COSMOSS using model ' ModelList{StructModel}])
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data_COSMOSS.hModel = app.hModel;
            
            assignin('base', 'Data_COSMOSS', Data_COSMOSS)
            disp('Updated Data_COSMOSS exported!')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COSMOSS_UIFigure = uifigure;
            app.COSMOSS_UIFigure.Name = 'COSMOSS V2.0';
            app.Panel_Structure = uipanel(app.COSMOSS_UIFigure);
            app.Panel_Structure.Title = 'Build Model Structure';
            app.ListBox = uilistbox(app.Panel_Structure);
            app.ListBox.Items = {'Two Couple Oscillators Model', 'Amide-I modes from PDB structure', '2D Grid from formatted ab initio output', 'Amide-I of ideal beta-sheet', 'Combination of any two model above'};
            app.ListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'1'};
            app.Button_SelectModel = uibutton(app.Panel_Structure, 'push');
            app.Button_SelectModel.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectModelPushed, true);
            app.Button_SelectModel.Text = 'Constructing structural model';
            app.Panel_Response = uipanel(app.COSMOSS_UIFigure);
            app.Panel_Response.Title = 'Molecular Response';
            app.LocalmodeFreqDropDownLabel = uilabel(app.Panel_Response);
            app.LocalmodeFreqDropDownLabel.Text = 'Local mode Freq.';
            app.LocalmodeFreqDropDown = uidropdown(app.Panel_Response);
            app.CouplingModelDropDownLabel = uilabel(app.Panel_Response);
            app.CouplingModelDropDownLabel.Text = 'Coupling Model';
            app.CouplingModelDropDown = uidropdown(app.Panel_Response);
            app.BetaNNcm1Label = uilabel(app.Panel_Response);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.BetaNNcm1EditField = uieditfield(app.Panel_Response, 'numeric');
            app.FeynmannpathwayCutoffEditFieldLabel = uilabel(app.Panel_Response);
            app.FeynmannpathwayCutoffEditFieldLabel.Text = 'Feynmann pathway Cutoff';
            app.FeynmannpathwayCutoffEditField = uieditfield(app.Panel_Response, 'numeric');
            app.Panel_ExpSetup = uipanel(app.COSMOSS_UIFigure);
            app.Panel_ExpSetup.Title = 'Experimental Setting';
            app.TabGroup = uitabgroup(app.Panel_ExpSetup);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '1D';
            app.UITable_1D = uitable(app.DTab);
            app.UITable_1D.ColumnName = {'IR'; 'Visible'; 'Signal'};
            app.UITable_1D.RowName = {};
            app.UITable_1D.ColumnEditable = [false false true];
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '2D';
            app.UITable_2D = uitable(app.DTab_2);
            app.UITable_2D.ColumnName = {'Pump1'; 'Pump2'; 'Probe'; 'Visible'; 'Signal'};
            app.UITable_2D.RowName = {};
            app.Panel_FigOpt = uipanel(app.COSMOSS_UIFigure);
            app.Panel_FigOpt.Title = 'Figure Setting';
            app.CheckBox_SaveFig = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_SaveFig.Text = 'Save Fig to: ';
            app.EditField_SavePath = uieditfield(app.Panel_FigOpt, 'text');
            app.EditField_SavePath.Value = '~/Desktop/TMP';
            app.CheckBox_Sticks = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Sticks.Text = 'Sticks';
            app.CheckBox_Normalize = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Normalize.Text = 'Normalize';
            app.CheckBox_Cursor = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Cursor.Text = 'Cursor';
            app.FreqMinLabel = uilabel(app.Panel_FigOpt);
            app.FreqMinLabel.Text = 'Freq. Min:';
            app.EditField_FreqMin = uieditfield(app.Panel_FigOpt, 'numeric');
            app.MaxLabel = uilabel(app.Panel_FigOpt);
            app.MaxLabel.Text = 'Max:';
            app.EditField_FreqMax = uieditfield(app.Panel_FigOpt, 'numeric');
            app.LineShapeLabel = uilabel(app.Panel_FigOpt);
            app.LineShapeLabel.Text = 'Line Shape:';
            app.DropDown_LineShape = uidropdown(app.Panel_FigOpt);
            app.WidthLabel = uilabel(app.Panel_FigOpt);
            app.WidthLabel.Text = 'Width:';
            app.EditField_Width = uieditfield(app.Panel_FigOpt, 'numeric');
            app.ContourEditFieldLabel = uilabel(app.Panel_FigOpt);
            app.ContourEditFieldLabel.Text = 'Contour#:';
            app.EditField_Contour = uieditfield(app.Panel_FigOpt, 'numeric');
            app.ColorMapDropDownLabel = uilabel(app.Panel_FigOpt);
            app.ColorMapDropDownLabel.Text = 'Color Map:';
            app.ColorMapDropDown = uidropdown(app.Panel_FigOpt);
            app.SignalTypeDropDownLabel = uilabel(app.Panel_FigOpt);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.Panel_FigOpt);
            app.SpectralTypeDropDownLabel = uilabel(app.Panel_FigOpt);
            app.SpectralTypeDropDownLabel.Text = 'Spectral Type';
            app.SpectralTypeDropDown = uidropdown(app.Panel_FigOpt);
            app.PathwayDropDownLabel = uilabel(app.Panel_FigOpt);
            app.PathwayDropDownLabel.Text = 'Pathway';
            app.PathwayDropDown = uidropdown(app.Panel_FigOpt);
            app.Panel_AnalysisTools = uipanel(app.COSMOSS_UIFigure);
            app.Panel_AnalysisTools.Title = 'Analysis Tools';
            app.TabGroup2 = uitabgroup(app.Panel_AnalysisTools);
            app.FTIRTab = uitab(app.TabGroup2);
            app.FTIRTab.Title = 'FTIR';
            app.SFGTab = uitab(app.TabGroup2);
            app.SFGTab.Title = 'SFG';
            app.DIRTab = uitab(app.TabGroup2);
            app.DIRTab.Title = '2D IR';
            app.DSFGTab = uitab(app.TabGroup2);
            app.DSFGTab.Title = '2D SFG';
            app.FTIRButton = uibutton(app.COSMOSS_UIFigure, 'push');
            app.FTIRButton.Text = 'FTIR';
            app.SFGButton = uibutton(app.COSMOSS_UIFigure, 'push');
            app.SFGButton.Text = 'SFG';
            app.DIRButton = uibutton(app.COSMOSS_UIFigure, 'push');
            app.DIRButton.Text = '2D IR';
            app.DSFGButton = uibutton(app.COSMOSS_UIFigure, 'push');
            app.DSFGButton.Text = '2D SFG';
            app.ExportDataButton = uibutton(app.COSMOSS_UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Panel_SampleSymmetry = uipanel(app.COSMOSS_UIFigure);
            app.Panel_SampleSymmetry.Title = 'Sample Symmetry';
            app.RotationalAverageDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.RotationalAverageDropDownLabel.Text = 'Rotational Average';
            app.RotationalAverageDropDown = uidropdown(app.Panel_SampleSymmetry);
            app.MirrorPlaneDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.MirrorPlaneDropDownLabel.Text = 'Mirror Plane';
            app.MirrorPlaneDropDown = uidropdown(app.Panel_SampleSymmetry);
            app.Panel_EnsembleAverage = uipanel(app.COSMOSS_UIFigure);
            app.Panel_EnsembleAverage.Title = 'Ensemble Average';
            app.RandomSamplingCheckBox = uicheckbox(app.Panel_EnsembleAverage);
            app.RandomSamplingCheckBox.Text = 'Random Sampling';
            app.SampleEditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.SampleEditFieldLabel.Text = 'Sample #';
            app.SampleEditField = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.DynamicFigUpdateCheckBox = uicheckbox(app.Panel_EnsembleAverage);
            app.DynamicFigUpdateCheckBox.Text = 'Dynamic Fig Update';
            app.ContinueCheckBox = uicheckbox(app.Panel_EnsembleAverage);
            app.ContinueCheckBox.Text = 'Continue?';
            app.FluctuationCorrelationLabel = uilabel(app.Panel_EnsembleAverage);
            app.FluctuationCorrelationLabel.Text = 'Fluctuation Correlation (%)';
            app.FluctuationCorrelationEditField = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.DiagonalDisorderFWHMcm1EditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.DiagonalDisorderFWHMcm1EditFieldLabel.Text = 'Diagonal Disorder FWHM (cm-1)';
            app.DiagonalDisorderFWHMcm1EditField = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.OffdiagonalDisorderFWHMcm1EditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.OffdiagonalDisorderFWHMcm1EditFieldLabel.Text = 'Off-diagonal Disorder FWHM (cm-1)';
            app.OffdiagonalDisorderFWHMcm1EditField = uieditfield(app.Panel_EnsembleAverage, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = COSMOSS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COSMOSS_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COSMOSS_UIFigure)
        end
    end
end